classdef pieceAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VisorBieleUIFigure  matlab.ui.Figure
        Toolbar             matlab.ui.container.Toolbar
        GridLayout          matlab.ui.container.GridLayout
        GridLayout2         matlab.ui.container.GridLayout
        TabGroup2           matlab.ui.container.TabGroup
        Tab                 matlab.ui.container.Tab
        canva               matlab.ui.control.UIAxes
        configTabs          matlab.ui.container.TabGroup
        loadFiles           matlab.ui.container.Tab
        GridLayout3         matlab.ui.container.GridLayout
        loadImageButton     matlab.ui.control.Button
        previewImage        matlab.ui.control.Image
        loadSVGButton       matlab.ui.control.Button
        previewSVG          matlab.ui.control.Image
    end

    
    properties (Access = public)
        imageData struct = struct( ...
            'fileName', '', ...
            'fullPath', '', ...
            'matrix', []);

        svgData = struct( ...
            'fileName','', ...
            'fullPath','', ...
            'contours', {{}}); 


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %gui.setupLayout(app);

            % Configuración del lienzo
            title(app.canva, '');
            app.canva.Toolbar.Visible = 'off';

            % Código correspondiente al procesamiento
            addpath(genpath('src'));
        end

        % Button pushed function: loadImageButton
        function loadImageButtonPushed(app, event)
            app.imageData = controllers.ImageController.loadImageFromDialog( ...
                app.previewImage, app.canva);
        end

        % Image clicked function: previewImage
        function previewImageClicked(app, event)
            if isempty(app.imageData.fileName)
                return;
            end
        
            gui.canvas.showPicture(app.canva, app.imageData.matrix);
        end

        % Button pushed function: loadSVGButton
        function loadSVGButtonPushed(app, event)
            app.svgData = controllers.SVGController.loadSVGFromDialog(app.previewSVG, ...
                app.canva);
        end

        % Image clicked function: previewSVG
        function previewSVGClicked(app, event)
            if isempty(app.actualSVG)
                return;
            end
        
            cla(app.canva);
            hold(app.canva, 'on');
            for i = 1:numel(app.actualSVG)
                coords = app.actualSVG{i};
                plot(app.canva, coords(:,1), coords(:,2), 'k', 'LineWidth', 1);
            end
            axis(app.canva, 'equal');
            title(app.canva, 'Modelo SVG cargado');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.VisorBieleUIFigure = uifigure('Visible', 'off');
            app.VisorBieleUIFigure.Name = 'Visor Biele';
            app.VisorBieleUIFigure.Icon = fullfile(pathToMLAPP, 'logoBiele.png');
            app.Toolbar = uitoolbar(app.VisorBieleUIFigure);
            app.GridLayout = uigridlayout(app.VisorBieleUIFigure);
            app.configTabs = uitabgroup(app.GridLayout);
            app.loadFiles = uitab(app.configTabs);
            app.loadFiles.Title = 'Botonera';
            app.GridLayout3 = uigridlayout(app.loadFiles);
            app.previewSVG = uiimage(app.GridLayout3);
            app.previewSVG.ImageClickedFcn = createCallbackFcn(app, @previewSVGClicked, true);
            app.loadSVGButton = uibutton(app.GridLayout3, 'push');
            app.loadSVGButton.ButtonPushedFcn = createCallbackFcn(app, @loadSVGButtonPushed, true);
            app.loadSVGButton.Text = 'Cargar SVG';
            app.previewImage = uiimage(app.GridLayout3);
            app.previewImage.ImageClickedFcn = createCallbackFcn(app, @previewImageClicked, true);
            app.loadImageButton = uibutton(app.GridLayout3, 'push');
            app.loadImageButton.ButtonPushedFcn = createCallbackFcn(app, @loadImageButtonPushed, true);
            app.loadImageButton.Text = 'Cargar imagen';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.canva = uiaxes(app.GridLayout2);
            title(app.canva, 'Title')
            xlabel(app.canva, 'X')
            ylabel(app.canva, 'Y')
            zlabel(app.canva, 'Z')
            app.TabGroup2 = uitabgroup(app.GridLayout2);
            app.TabGroup2.Visible = 'off';
            app.Tab = uitab(app.TabGroup2);
            app.Tab.Title = 'Tab';
            % Show the figure after all components are created
            app.VisorBieleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pieceAnalyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VisorBieleUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VisorBieleUIFigure)
        end
    end
end